classdef AWGSimulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AWGSimulatorUIFigure            matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        DesignParametersPanel           matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        CenterWavelengthmEditFieldLabel  matlab.ui.control.Label
        CenterWavelengthmEditField      matlab.ui.control.NumericEditField
        NumberofarrayedwaveguidesEditFieldLabel  matlab.ui.control.Label
        NumberofarrayedwaveguidesEditField  matlab.ui.control.NumericEditField
        DiffractionorderEditFieldLabel  matlab.ui.control.Label
        DiffractionorderEditField       matlab.ui.control.NumericEditField
        RadiusofcurvaturemEditFieldLabel  matlab.ui.control.Label
        RadiusofcurvaturemEditField     matlab.ui.control.NumericEditField
        ArrayaperturespacingmEditFieldLabel  matlab.ui.control.Label
        ArrayaperturespacingmEditField  matlab.ui.control.NumericEditField
        ArrayaperturegapwidthmEditFieldLabel  matlab.ui.control.Label
        ArrayaperturegapwidthmEditField  matlab.ui.control.NumericEditField
        ArraywavwguidecorewidthnmEditFieldLabel  matlab.ui.control.Label
        ArraywavwguidecorewidthnmEditField  matlab.ui.control.NumericEditField
        ProcessParametersPanel          matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        BottomcladdingmaterialDropDownLabel  matlab.ui.control.Label
        BottomcladdingmaterialDropDown  matlab.ui.control.DropDown
        TopcladdingmaterialDropDownLabel  matlab.ui.control.Label
        TopcladdingmaterialDropDown     matlab.ui.control.DropDown
        CorematerialDropDownLabel       matlab.ui.control.Label
        CorematerialDropDown            matlab.ui.control.DropDown
        CorematerialthicknessnmEditFieldLabel  matlab.ui.control.Label
        CorematerialthicknessnmEditField  matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        InputSectionTab                 matlab.ui.container.Tab
        GridLayout5_3                   matlab.ui.container.GridLayout
        NumberofinputsEditFieldLabel    matlab.ui.control.Label
        NumberofinputsEditField         matlab.ui.control.NumericEditField
        AperturewidthmEditFieldLabel    matlab.ui.control.Label
        InputAperturewidthmEditField    matlab.ui.control.NumericEditField
        AperturespacingmEditFieldLabel  matlab.ui.control.Label
        InputAperturespacingmEditField  matlab.ui.control.NumericEditField
        AperturecenteroffsetmEditFieldLabel  matlab.ui.control.Label
        InputAperturecenteroffsetmEditField  matlab.ui.control.NumericEditField
        OutputSectionTab                matlab.ui.container.Tab
        GridLayout5_4                   matlab.ui.container.GridLayout
        AperturewidthmEditField_2Label  matlab.ui.control.Label
        OutputAperturewidthmEditField   matlab.ui.control.NumericEditField
        AperturespacingmEditField_2Label  matlab.ui.control.Label
        OutputAperturespacingmEditField  matlab.ui.control.NumericEditField
        AperturecenteroffsetmEditField_2Label  matlab.ui.control.Label
        OutputAperturecenteroffsetmEditField  matlab.ui.control.NumericEditField
        SameasinputCheckBox             matlab.ui.control.CheckBox
        NumberofoutputsEditFieldLabel   matlab.ui.control.Label
        NumberofoutputsEditField        matlab.ui.control.NumericEditField
        GridLayout7                     matlab.ui.container.GridLayout
        SimulateButton                  matlab.ui.control.Button
        SimulationbandwidthnmEditFieldLabel  matlab.ui.control.Label
        SimulationbandwidthnmEditField  matlab.ui.control.NumericEditField
        QualityLabel                    matlab.ui.control.Label
        QualitySlider                   matlab.ui.control.Slider
        RightPanel                      matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        SpectrumPlot                    matlab.ui.control.UIAxes
        ResultsTable                    matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            basepath = matlab.apputil.getInstalledAppInfo().location;
            
            % generate list of material names
            names = {dir(basepath + "/materials/").name};
            names = strrep(names(~strncmp(names, '.', 1)), ".m", "");
            
            % set material list items
            app.TopcladdingmaterialDropDown.Items = names;
            app.BottomcladdingmaterialDropDown.Items = names;
            app.CorematerialDropDown.Items = names;
        end

        % Value changed function: SameasinputCheckBox
        function SameasinputCheckBoxValueChanged(app, event)
            value = app.SameasinputCheckBox.Value;
            app.OutputAperturewidthmEditField.Enable=~value;
            app.OutputAperturespacingmEditField.Enable=~value;
            app.OutputAperturecenteroffsetmEditField.Enable=~value;
            if value == true
                app.OutputAperturewidthmEditField.Value=app.InputAperturewidthmEditField.Value;
                app.OutputAperturespacingmEditField.Value=app.InputAperturespacingmEditField.Value;
                app.OutputAperturecenteroffsetmEditField.Value=app.InputAperturecenteroffsetmEditField.Value;
            end
        end

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            clad_name = app.TopcladdingmaterialDropDown.Value;
            core_name = app.CorematerialDropDown.Value;
            subs_name = app.BottomcladdingmaterialDropDown.Value;
            
            AWG = struct();
            AWG.lambda_c = app.CenterWavelengthmEditField.Value;
            AWG.clad = str2func(clad_name);
            AWG.core = str2func(core_name);
            AWG.subs = str2func(subs_name);
            AWG.w = 1e-3 * app.ArraywavwguidecorewidthnmEditField.Value;
            AWG.h = 1e-3 * app.CorematerialthicknessnmEditField.Value;
            AWG.N = app.NumberofoutputsEditField.Value;
            AWG.M = app.NumberofarrayedwaveguidesEditField.Value;
            AWG.m = app.DiffractionorderEditField.Value;
            AWG.R = app.RadiusofcurvaturemEditField.Value;
            AWG.d = app.ArrayaperturespacingmEditField.Value;
            AWG.do = app.OutputAperturespacingmEditField.Value;
            AWG.wi = app.InputAperturewidthmEditField.Value;
            AWG.wg = AWG.d - app.ArrayaperturegapwidthmEditField.Value;
            AWG.wo = app.OutputAperturewidthmEditField.Value;

            SimBandwidth = 1e-3 * app.SimulationbandwidthnmEditField.Value;
            NumPoints = app.QualitySlider.Value * 100;
            
             % generate simulation wavelengths
            lambda = AWG.lambda_c + linspace(-1/2,+1/2,NumPoints) * SimBandwidth;
        
            % calculate transmission data
            T = zeros(NumPoints,AWG.N);
            f = uiprogressdlg(app.AWGSimulatorUIFigure, "Title", "Computing...");
            for i = 1:NumPoints
                if ~isvalid(f)
                    warning("Computation was aborted by user...");
                    break;
                end
                f.Value = i/NumPoints;
                f.Message = "Computing response for wavelength: " + num2str(lambda(i)) + " µm";
                
                % simulate at wavelength
                T(i,:) = awg_simulate(AWG, lambda(i));
            end
            
            if isvalid(f)
                close(f)
            end
            
            table = awg_analyse(lambda, T);
            app.ResultsTable.Data = table.Value;
            app.ResultsTable.RowName = table.Row;
            
            plot(app.SpectrumPlot, lambda, 10*log10(T), 'LineWidth', 2)
            xlabel(app.SpectrumPlot, 'Wavelength [µm]')
            ylabel(app.SpectrumPlot, 'Insertion Loss [dB]')
            xlim(app.SpectrumPlot, [lambda(1),lambda(end)])
            ylim(app.SpectrumPlot, [-40,0]);
            legend(app.SpectrumPlot, "channel " + num2str((1:AWG.N)'))
            drawnow;
        end

        % Value changed function: QualitySlider
        function QualitySliderValueChanged(app, event)
            value = app.QualitySlider.Value;
            app.QualitySlider.Value = round(value, 0);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AWGSimulatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {822, 822};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {365, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AWGSimulatorUIFigure = uifigure('Visible', 'off');
            app.AWGSimulatorUIFigure.Name = 'AWG Simulator';
            app.AWGSimulatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.AWGSimulatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.DesignParametersPanel = uipanel(app.GridLayout2);
            app.DesignParametersPanel.Title = 'Design Parameters';
            app.GridLayout4 = uigridlayout(app.DesignParametersPanel);
            app.CenterWavelengthmEditFieldLabel = uilabel(app.GridLayout4);
            app.CenterWavelengthmEditFieldLabel.Text = 'Center Wavelength [µm]:';
            app.CenterWavelengthmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.CenterWavelengthmEditField.Limits = [0 Inf];
            app.CenterWavelengthmEditField.ValueDisplayFormat = '%11.4f';
            app.CenterWavelengthmEditField.Value = 1.55;
            app.NumberofarrayedwaveguidesEditFieldLabel = uilabel(app.GridLayout4);
            app.NumberofarrayedwaveguidesEditFieldLabel.Text = 'Number of arrayed waveguides:';
            app.NumberofarrayedwaveguidesEditField = uieditfield(app.GridLayout4, 'numeric');
            app.NumberofarrayedwaveguidesEditField.Limits = [1 Inf];
            app.NumberofarrayedwaveguidesEditField.ValueDisplayFormat = '%i';
            app.NumberofarrayedwaveguidesEditField.Value = 40;
            app.DiffractionorderEditFieldLabel = uilabel(app.GridLayout4);
            app.DiffractionorderEditFieldLabel.Text = 'Diffraction order:';
            app.DiffractionorderEditField = uieditfield(app.GridLayout4, 'numeric');
            app.DiffractionorderEditField.Limits = [0 Inf];
            app.DiffractionorderEditField.ValueDisplayFormat = '%i';
            app.DiffractionorderEditField.Value = 30;
            app.RadiusofcurvaturemEditFieldLabel = uilabel(app.GridLayout4);
            app.RadiusofcurvaturemEditFieldLabel.Text = 'Radius of curvature [µm]:';
            app.RadiusofcurvaturemEditField = uieditfield(app.GridLayout4, 'numeric');
            app.RadiusofcurvaturemEditField.Limits = [0 Inf];
            app.RadiusofcurvaturemEditField.Value = 100;
            app.ArrayaperturespacingmEditFieldLabel = uilabel(app.GridLayout4);
            app.ArrayaperturespacingmEditFieldLabel.Text = 'Array aperture spacing [µm]:';
            app.ArrayaperturespacingmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ArrayaperturespacingmEditField.Limits = [0 Inf];
            app.ArrayaperturespacingmEditField.Value = 1.6;
            app.ArrayaperturegapwidthmEditFieldLabel = uilabel(app.GridLayout4);
            app.ArrayaperturegapwidthmEditFieldLabel.Text = 'Array aperture gap width [µm]:';
            app.ArrayaperturegapwidthmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ArrayaperturegapwidthmEditField.Value = 1.3;
            app.ArraywavwguidecorewidthnmEditFieldLabel = uilabel(app.GridLayout4);
            app.ArraywavwguidecorewidthnmEditFieldLabel.Text = 'Array wavwguide core width [nm]:';
            app.ArraywavwguidecorewidthnmEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ArraywavwguidecorewidthnmEditField.Limits = [0 Inf];
            app.ArraywavwguidecorewidthnmEditField.Value = 450;
            app.ProcessParametersPanel = uipanel(app.GridLayout2);
            app.ProcessParametersPanel.Title = 'Process Parameters';
            app.GridLayout6 = uigridlayout(app.ProcessParametersPanel);
            app.BottomcladdingmaterialDropDownLabel = uilabel(app.GridLayout6);
            app.BottomcladdingmaterialDropDownLabel.Text = 'Bottom cladding material:';
            app.BottomcladdingmaterialDropDown = uidropdown(app.GridLayout6);
            app.BottomcladdingmaterialDropDown.Items = {'SiO2'};
            app.BottomcladdingmaterialDropDown.Value = 'SiO2';
            app.TopcladdingmaterialDropDownLabel = uilabel(app.GridLayout6);
            app.TopcladdingmaterialDropDownLabel.Text = 'Top cladding material:';
            app.TopcladdingmaterialDropDown = uidropdown(app.GridLayout6);
            app.TopcladdingmaterialDropDown.Items = {'Air'};
            app.TopcladdingmaterialDropDown.Value = 'Air';
            app.CorematerialDropDownLabel = uilabel(app.GridLayout6);
            app.CorematerialDropDownLabel.Text = 'Core material:';
            app.CorematerialDropDown = uidropdown(app.GridLayout6);
            app.CorematerialDropDown.Items = {'Si'};
            app.CorematerialDropDown.Value = 'Si';
            app.CorematerialthicknessnmEditFieldLabel = uilabel(app.GridLayout6);
            app.CorematerialthicknessnmEditFieldLabel.Text = 'Core material thickness [nm]:';
            app.CorematerialthicknessnmEditField = uieditfield(app.GridLayout6, 'numeric');
            app.CorematerialthicknessnmEditField.Value = 220;
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.InputSectionTab = uitab(app.TabGroup);
            app.InputSectionTab.Title = 'Input Section';
            app.GridLayout5_3 = uigridlayout(app.InputSectionTab);
            app.NumberofinputsEditFieldLabel = uilabel(app.GridLayout5_3);
            app.NumberofinputsEditFieldLabel.Text = 'Number of inputs:';
            app.NumberofinputsEditField = uieditfield(app.GridLayout5_3, 'numeric');
            app.NumberofinputsEditField.Limits = [0 Inf];
            app.NumberofinputsEditField.ValueDisplayFormat = '%i';
            app.NumberofinputsEditField.Value = 1;
            app.AperturewidthmEditFieldLabel = uilabel(app.GridLayout5_3);
            app.AperturewidthmEditFieldLabel.Text = 'Aperture width [µm]:';
            app.InputAperturewidthmEditField = uieditfield(app.GridLayout5_3, 'numeric');
            app.InputAperturewidthmEditField.Limits = [0 Inf];
            app.InputAperturewidthmEditField.Value = 1;
            app.AperturespacingmEditFieldLabel = uilabel(app.GridLayout5_3);
            app.AperturespacingmEditFieldLabel.Text = 'Aperture spacing [µm]:';
            app.InputAperturespacingmEditField = uieditfield(app.GridLayout5_3, 'numeric');
            app.InputAperturespacingmEditField.Limits = [0 Inf];
            app.AperturecenteroffsetmEditFieldLabel = uilabel(app.GridLayout5_3);
            app.AperturecenteroffsetmEditFieldLabel.Text = 'Aperture center offset [µm];';
            app.InputAperturecenteroffsetmEditField = uieditfield(app.GridLayout5_3, 'numeric');
            app.InputAperturecenteroffsetmEditField.Limits = [0 Inf];
            app.OutputSectionTab = uitab(app.TabGroup);
            app.OutputSectionTab.Title = 'Output Section';
            app.GridLayout5_4 = uigridlayout(app.OutputSectionTab);
            app.AperturewidthmEditField_2Label = uilabel(app.GridLayout5_4);
            app.AperturewidthmEditField_2Label.Text = 'Aperture width [µm]:';
            app.OutputAperturewidthmEditField = uieditfield(app.GridLayout5_4, 'numeric');
            app.OutputAperturewidthmEditField.Limits = [0 Inf];
            app.OutputAperturewidthmEditField.Value = 1;
            app.AperturespacingmEditField_2Label = uilabel(app.GridLayout5_4);
            app.AperturespacingmEditField_2Label.Text = 'Aperture spacing [µm]:';
            app.OutputAperturespacingmEditField = uieditfield(app.GridLayout5_4, 'numeric');
            app.OutputAperturespacingmEditField.Limits = [0 Inf];
            app.OutputAperturespacingmEditField.Value = 3.6;
            app.AperturecenteroffsetmEditField_2Label = uilabel(app.GridLayout5_4);
            app.AperturecenteroffsetmEditField_2Label.Text = 'Aperture center offset [µm];';
            app.OutputAperturecenteroffsetmEditField = uieditfield(app.GridLayout5_4, 'numeric');
            app.OutputAperturecenteroffsetmEditField.Limits = [0 Inf];
            app.SameasinputCheckBox = uicheckbox(app.GridLayout5_4);
            app.SameasinputCheckBox.ValueChangedFcn = createCallbackFcn(app, @SameasinputCheckBoxValueChanged, true);
            app.SameasinputCheckBox.Text = 'Same as input?';
            app.NumberofoutputsEditFieldLabel = uilabel(app.GridLayout5_4);
            app.NumberofoutputsEditFieldLabel.Text = 'Number of outputs:';
            app.NumberofoutputsEditField = uieditfield(app.GridLayout5_4, 'numeric');
            app.NumberofoutputsEditField.Limits = [0 Inf];
            app.NumberofoutputsEditField.ValueDisplayFormat = '%i';
            app.NumberofoutputsEditField.Value = 9;
            app.GridLayout7 = uigridlayout(app.GridLayout2);
            app.SimulateButton = uibutton(app.GridLayout7, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.SimulationbandwidthnmEditFieldLabel = uilabel(app.GridLayout7);
            app.SimulationbandwidthnmEditFieldLabel.Text = 'Simulation bandwidth [nm]:';
            app.SimulationbandwidthnmEditField = uieditfield(app.GridLayout7, 'numeric');
            app.SimulationbandwidthnmEditField.Limits = [0.1 Inf];
            app.SimulationbandwidthnmEditField.Value = 10;
            app.QualityLabel = uilabel(app.GridLayout7);
            app.QualityLabel.Text = 'Quality:';
            app.QualitySlider = uislider(app.GridLayout7);
            app.QualitySlider.Limits = [1 5];
            app.QualitySlider.MajorTicks = [1 2 3 4 5];
            app.QualitySlider.ValueChangedFcn = createCallbackFcn(app, @QualitySliderValueChanged, true);
            app.QualitySlider.MinorTicks = [];
            app.QualitySlider.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.SpectrumPlot = uiaxes(app.GridLayout3);
            title(app.SpectrumPlot, 'Device Insertion Loss Spectrum')
            xlabel(app.SpectrumPlot, 'Wavelength [µm]')
            ylabel(app.SpectrumPlot, 'Insertion Loss [dB]')
            app.ResultsTable = uitable(app.GridLayout3);
            app.ResultsTable.ColumnName = {''};
            app.ResultsTable.RowName = {''};
            % Show the figure after all components are created
            app.AWGSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AWGSimulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AWGSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AWGSimulatorUIFigure)
        end
    end
end